classdef LocalFinding < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        AxesOverview         matlab.ui.control.UIAxes
        AxesDetail           matlab.ui.control.UIAxes
        ChntngDropDownLabel  matlab.ui.control.Label
        LevelDropDown        matlab.ui.control.DropDown
        Panel                matlab.ui.container.Panel
        NodeTable            matlab.ui.control.Table
        GatewayTable         matlab.ui.control.Table
        VtrccgatewayLabel    matlab.ui.control.Label
        ThngtinccnodeLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        presenter Presenter
        updating_timer % Description
        listOfObjectView %Store list of scatter3 objects
        listNodes % Description
        listGateways % Description
        
        scatterOverview
        scatterDetail
        patchModelOverview
        PatchModelDetail
    end
    
    methods (Access = public)
        %================================================================
        %Drawing a part of model
        %================================================================
        function plotModel(app, modelInformation, axesIndex)
            switch axesIndex
                case 1
                    axes = app.AxesOverview;
                    app.patchModelOverview = app.createPatchs(modelInformation, axes);
                    light(axes);
                    daspect(axes, [1 1 1])
                    view(axes, 3)
                    xlabel(axes,'X'),ylabel(axes, 'Y'),zlabel(axes,'Z')
                    rotate3d(axes, 'on');
                case 2
                    axes = app.AxesDetail;
                    app.PatchModelDetail = app.createPatchs(modelInformation, axes);
                    light(axes);
                    daspect(axes, [1 1 1])
                    view(axes, 3)
                    xlabel(axes,'X'),ylabel(axes, 'Y'),zlabel(axes,'Z')
                    rotate3d(axes, 'on');
            end
        end
        
        function patchs = createPatchs(app, modelInformation, axes)
            faces = modelInformation.faces;
            vertexes = modelInformation.vertexes;
            colors = modelInformation.colors;
            patchs = patch(axes, 'faces', faces, 'vertices', vertexes);
            set(patchs, 'facec', 'flat');
            set(patchs, 'FaceVertexCData', colors);
            set(patchs, 'EdgeColor', [0.2980 0.6863 0.6667]);
        end
        
        function updatePatchs(app, modelInformation, patchs)
            faces = modelInformation.faces;
            vertexes = modelInformation.vertexes;
            colors = modelInformation.colors;
            set(patchs, 'faces', faces, 'vertices', vertexes, 'FaceVertexCData', colors);
        end
    end
    
    methods (Access = private)
        %================================================================
        %Build model
        %================================================================
        %================================================================
        function buildModel(app)
            %------------------------------------------------------------
            %Building model on AxesOverview
            %------------------------------------------------------------
            modelData = app.presenter.modelData;
            try
                currentLevel = str2double(app.LevelDropDown.Value);
            catch
                currentLevel = 1;
            end
            
            detailData = app.presenter.getModelDataAt(currentLevel);
            app.plotModel(modelData, 1);
            app.plotModel(detailData, 2);
            %------------------------------------------------------------
            %Draw list object
            %------------------------------------------------------------
            app.displayObjectOnModel();
            app.displayObjectAt(currentLevel);
        end
        
        %================================================================
        %Fill data into table
        %================================================================
        %================================================================
        function timer_tick(object, timerObj, timerData)
            listNewNodes = object.presenter.getListNodes();
            disp(listNewNodes(1).data.x);
            for i = 1: length(listNewNodes)
                for j = 1: length(object.listNodes)
                    object.listNodes(i) = object.listNodes(i).updateDataIfNeeded(listNewNodes(i));
                end
            end
            object.fillTableData();
            object.updateObjectOnModel();
        end
        
        function fillTableData(app)
            nGateways = length(app.listGateways);
            nNodes = length(app.listNodes);
            gatewaysData = cell(nGateways, 5);
            nodesData = cell(nNodes, 5);
            for i = 1: nGateways
                gateway = app.listGateways(i);
                gatewaysData{i,1} = gateway.data.id;
                gatewaysData{i,2} = gateway.data.name;
                gatewaysData{i,3} = gateway.data.x;
                gatewaysData{i,4} = gateway.data.y;
                gatewaysData{i,5} = gateway.data.z;
            end
            
            for i = 1: nNodes
                node = app.listNodes(i);
                nodesData{i,1} = node.data.id;
                nodesData{i,2} = node.data.name;
                nodesData{i,3} = 'null';
                nodesData{i,4} = node.locationName;
                nodesData{i,5} = '';
            end
            
            app.GatewayTable.Data = gatewaysData;
            app.NodeTable.Data = nodesData;
        end
        
        %================================================================
        %Updating list of objects
        %================================================================
        %================================================================
        function updateObjectOnModel(app)
            app.displayObjectOnModel();
            
            try
                currentLevel = str2double(app.LevelDropDown.Value);
            catch
                currentLevel = 1;
            end
            app.displayObjectAt(currentLevel);
        end
        
        %================================================================
        %Updating a object
        %================================================================
        function updateObject(app, listObjects, axesIndex)
            [outX, outY, outZ, outC, outText] = app.getListScatterData(listObjects);
            switch axesIndex
                case 1
                    set(app.scatterOverview.point, 'XData', outX, 'YData', outY, 'ZData', outZ, 'CData', outC);
                    %                     set(app.scatterOverview.text, 'TextData', outText, 'ColorData', outC);
                case 2
                    set(app.scatterDetail.point, 'XData', outX, 'YData', outY, 'ZData', outZ, 'CData', outC);
                    %                     set(app.scatterDetail.text, 'TextData', outText, 'ColorData', outC);
            end
            
        end
        
        %================================================================
        %Drawing list of objects on detail
        %================================================================
        %================================================================
        function displayObjectAt(app, currentLevel)
            nGateways = length(app.listGateways);
            nNodes = length(app.listNodes);
            gatewaysData = repmat(ObjectView(), nGateways, 1);
            nodesData = repmat(ObjectView(), nNodes, 1);
            gatewayIndex = 1;
            nodeIndex = 1;
            
            for i = 1: length(app.listGateways)
                %--------------------------------------------------------
                %If object is on current level then draw it on detail axes
                %--------------------------------------------------------
                if app.listGateways(i).level == currentLevel
                    gatewaysData(gatewayIndex) = app.listGateways(i);
                    gatewayIndex = gatewayIndex + 1;
                end
            end
            
            for i = 1: length(app.listNodes)
                %--------------------------------------------------------
                %If object is on current level then draw it on detail axes.
                %--------------------------------------------------------
                if app.listNodes(i).level == currentLevel
                    nodesData(nodeIndex) = app.listNodes(i);
                    nodeIndex = nodeIndex + 1;
                end
            end
            
            objectData = [gatewaysData(1:gatewayIndex - 1); nodesData(1:nodeIndex - 1)];
            %If scatter is not exists => create it, orelse update it.
            if isempty(app.scatterDetail)
                app.drawObjects(objectData, 2);
            else
                app.updateObject(objectData, 2);
            end
        end
        
        %================================================================
        %Drawing list of objects on all model
        %================================================================
        %================================================================
        function displayObjectOnModel(app)
            objectData = [app.listGateways; app.listNodes];
            if isempty(app.scatterOverview)
                 app.drawObjects(objectData, 1);
            else
                app.updateObject(objectData, 1);
            end
        end
        
        %================================================================
        %Drawing a object
        %================================================================
        function drawObjects(app, listObjects, axesIndex)
            if isempty(listObjects)
                return;
            end
            [outX, outY, outZ, outC, outText] = app.getListScatterData(listObjects);
            switch axesIndex
                case 1
                    axes = app.AxesOverview;
                    hold(axes, 'on');
                    app.scatterOverview.point = scatter3(axes, outX, outY, outZ, 'filled', 'CData', outC);
                    %                     app.scatterOverview.text = textscatter3(axes, outX, outY, outZ, outText, 'ColorData', outC);
                    hold(axes, 'off');
                case 2
                    axes = app.AxesDetail;
                    hold(axes, 'on');
                    app.scatterDetail.point = scatter3(axes, outX, outY, outZ, 'filled', 'CData', outC);
                    %                     app.scatterDetail.text = textscatter3(axes, outX, outY, outZ, outText, 'ColorData', outC);
                    hold(axes, 'off');
            end
        end
        
        function [outX, outY, outZ, outC, outText] = getListScatterData(app, data)
            if isempty(data)
                outX = [];
                outY = [];
                outZ = [];
                outC = [];
                outText = [];
                return;
            end
            [s, ~] = size(data);
            outX = zeros(s,1);
            outY = zeros(s,1);
            outZ = zeros(s,1);
            outC = repmat([0 0 0], s, 1);
            outText = repmat(string, s, 1);
            for i = 1: s
                outX(i) = data(i).data.x;
                outY(i) = data(i).data.y;
                outZ(i) = data(i).data.z;
                outC(i, :) = data(i).color;
                outText(i) = data(i).data.name;
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.presenter = Presenter();
            app.listGateways = app.presenter.getListGateways();
            app.listNodes = app.presenter.getListNodes();
            app.buildModel()
            app.fillTableData();
            set(app.LevelDropDown, 'Items', app.presenter.getListOfFloors());
            
            app.updating_timer=timer('ExecutionMode', 'fixedRate', 'Period', 3, 'TimerFcn', {@app.timer_tick});
            start(app.updating_timer);
        end

        % Value changed function: LevelDropDown
        function LevelDropDownValueChanged(app, event)
            level = app.LevelDropDown.Value;
            try
                level = str2double(level);
            catch
                level = 1;
            end
            modelData = app.presenter.getModelDataAt(level);
            app.updatePatchs(modelData, app.PatchModelDetail);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.AxesOverview = uiaxes(app.UIFigure);
            title(app.AxesOverview, 'Model')
            xlabel(app.AxesOverview, 'X')
            ylabel(app.AxesOverview, 'Y')
            app.AxesDetail = uiaxes(app.UIFigure);
            title(app.AxesDetail, 'Title')
            xlabel(app.AxesDetail, 'X')
            ylabel(app.AxesDetail, 'Y')
            app.ChntngDropDownLabel = uilabel(app.UIFigure);
            app.ChntngDropDownLabel.Text = 'Chọn tầng';
            app.LevelDropDown = uidropdown(app.UIFigure);
            app.LevelDropDown.Items = {'1'};
            app.LevelDropDown.Editable = 'on';
            app.LevelDropDown.ValueChangedFcn = createCallbackFcn(app, @LevelDropDownValueChanged, true);
            app.LevelDropDown.Value = '1';
            app.Panel = uipanel(app.UIFigure);
            app.NodeTable = uitable(app.Panel);
            app.NodeTable.ColumnName = {'ID'; 'Tên'; 'Địa chỉ'; 'Vị trí phòng'; 'Ghi chú'};
            app.NodeTable.RowName = {};
            app.GatewayTable = uitable(app.Panel);
            app.GatewayTable.ColumnName = {'ID'; 'Tên'; 'X'; 'Y'; 'Z'};
            app.GatewayTable.RowName = {};
            app.VtrccgatewayLabel = uilabel(app.Panel);
            app.VtrccgatewayLabel.Text = 'Vị trí các gateway';
            app.ThngtinccnodeLabel = uilabel(app.Panel);
            app.ThngtinccnodeLabel.Text = 'Thông tin các node';
        end
    end

    methods (Access = public)

        % Construct app
        function app = LocalFinding

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end